classdef Passive_filter_design_and_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ButtonGroup                     matlab.ui.container.ButtonGroup
        HzButton                        matlab.ui.control.RadioButton
        radsButton                      matlab.ui.control.RadioButton
        SelectFilterDropDownLabel       matlab.ui.control.Label
        SelectFilterDropDown            matlab.ui.control.DropDown
        SelecttypeDropDownLabel         matlab.ui.control.Label
        SelecttypeDropDown              matlab.ui.control.DropDown
        PassiveFilterDesignandAnalysisLabel  matlab.ui.control.Label
        ShowBodePlotandCircuitDiagramButton  matlab.ui.control.Button
        CutoffFrequencyLabel            matlab.ui.control.Label
        CutoffFrequencyEditField        matlab.ui.control.NumericEditField
        CutoffFrequency2EditFieldLabel  matlab.ui.control.Label
        CutoffFrequency2EditField       matlab.ui.control.NumericEditField
        CharacteristicsLabel            matlab.ui.control.Label
        CharacteristicsTextArea         matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function lowPass_seriesRL(app, cutoff)
            if app.HzButton.Value == true
                wc= 2*pi*cutoff;
            else
                wc = cutoff;
            end
                      
            w=0:wc/100:100*wc;
            tau=1/wc;   
            H=1./(1+j*w*tau);
            Q = 1;
            B = wc; 
            R = 1000;
            format shortE;

            L = R/wc ;

            
            %Error Calculation
            error_function = @(w) 1 - abs(1./(1+j*w*tau));
            error = integral(error_function, 0, wc)/wc *100;
            if app.HzButton.Value == true
                semilogx(app.UIAxes2, w/(2*pi), 20*log10((abs(H))));

                semilogx(app.UIAxes2_2, w/(2*pi), angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (Hz)';
                app.UIAxes2_2.XLabel.String = 'Frequency (Hz)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L,"%.3e"), ' H'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B/(2*pi)), ' Hz'], ['Error = ', num2str(error), ' %']};
                
            else
                semilogx(app.UIAxes2, w, 20*log10((abs(H))));

                semilogx(app.UIAxes2_2, w, angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (rad/s)';
                app.UIAxes2_2.XLabel.String = 'Frequency (rad/s)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L,"%.3e"), ' H'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B), ' rad/s'],['Error = ', num2str(error), ' %']};

            end            


        end
        
        
        function highPass_seriesRL(app, cutoff)
            if app.HzButton.Value == true
                wc= 2*pi*cutoff;
            else
                wc = cutoff;
            end
            
            
            w = 0: wc/100: 133*wc;
            tau=1/wc;   
            H=(j*w*tau)./(1+j*w*tau);
            
            Q = 1;
            B = 'Inf';
            R = 1000;
            L = R/wc ;

            
            if app.HzButton.Value == true
                semilogx(app.UIAxes2, w/(2*pi), 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w/(2*pi), angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (Hz)';
                app.UIAxes2_2.XLabel.String = 'Frequency (Hz)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L,"%.3e"), ' H'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', B]};

            else
                semilogx(app.UIAxes2, w, 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w, angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (rad/s)';
                app.UIAxes2_2.XLabel.String = 'Frequency (rad/s)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L,"%.3e"), ' H'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', B]};
                
            end 
        end
        
        function lowPass_seriesRC(app, cutoff)
            if app.HzButton.Value == true
                wc= 2*pi*cutoff;
            else
                wc = cutoff;
            end            
            
            
            w = 0: wc/100: 133*wc;
            tau = 1/wc;  
            H = 1./(1+j*w*tau);
            Q = 1;
            B = wc; 
            
            R = 1000;
            C = 1/(R*wc);
            
            error_function = @(w) 1 - abs(1./(1+j*w*tau));
            error = integral(error_function, 0, wc)/wc *100;
            
            if app.HzButton.Value == true
                semilogx(app.UIAxes2, w/(2*pi), 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w/(2*pi), angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (Hz)';
                app.UIAxes2_2.XLabel.String = 'Frequency (Hz)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Capacitor Value, C = ', num2str(C,"%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B/(2*pi)), ' Hz'], ['Error = ', num2str(error), ' %']};

            else
                semilogx(app.UIAxes2, w, 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w, angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (rad/s)';
                app.UIAxes2_2.XLabel.String = 'Frequency (rad/s)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Capacitor Value, C = ', num2str(C,"%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B), ' rad/s'],['Error = ', num2str(error), ' %']};

            end           
        end
        
        function highPass_seriesRC(app, cutoff)
            if app.HzButton.Value == true
                wc= 2*pi*cutoff;
            else
                wc = cutoff;
            end

            w = 0 : wc/100 : 133*wc;
            tau=1/wc;  
            H=(j*w*tau)./(1+j*w*tau);
            Q = 1;
            B = 'Inf';    
            
            R = 1000;
            C = 1/(R*wc);
            
            if app.HzButton.Value == true
                semilogx(app.UIAxes2, w/(2*pi), 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w/(2*pi), angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (Hz)';
                app.UIAxes2_2.XLabel.String = 'Frequency (Hz)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Capacitor Value, C = ', num2str(C,"%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', B]};
                
            else
                semilogx(app.UIAxes2, w, 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w, angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (rad/s)';
                app.UIAxes2_2.XLabel.String = 'Frequency (rad/s)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Capacitor Value, C = ', num2str(C,"%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', B]};

            end 
        end
        
        function bandPass_seriesRLC(app, cutoff1, cutoff2)
            wc1 = min(cutoff1, cutoff2);
            wc2 = max(cutoff1, cutoff2);
            
            if app.HzButton.Value == true
                wc1= 2*pi*wc1;
                wc2= 2*pi*wc2;
            end
            
            show_alert_if_same_cutoff(app, wc1, wc2);
            
            B= abs(wc2-wc1);
            w0=sqrt(wc1*wc2);
            Q = round(w0/B, 3);
            
            R = 1e3;
            L = R/B;
            C = 1/(L*w0^2);
            
            w =0 : w0/500 : 133*max([wc1, wc2]);

            s = j*w;


            H = (s*B)./(s.^2 + s*B + w0^2);
            
            error_function = @(s) 1 - abs((s*B)./(s.^2 + s*B + w0^2));
            error = abs(integral(error_function, j*wc1, j*wc2))/B *100;
            
            if app.HzButton.Value == true
                semilogx(app.UIAxes2, w/(2*pi), 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w/(2*pi), angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (Hz)';
                app.UIAxes2_2.XLabel.String = 'Frequency (Hz)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L, "%.3e"), ' H'],['Capacitor Value, C = ', num2str(C, "%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B/(2*pi)), ' Hz'], ['Error = ', num2str(error), ' %']};

                
            else
                semilogx(app.UIAxes2, w, 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w, angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (rad/s)';
                app.UIAxes2_2.XLabel.String = 'Frequency (rad/s)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L, "%.3e"), ' H'],['Capacitor Value, C = ', num2str(C, "%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B), ' rad/s'],['Error = ', num2str(error), ' %']};
              
            end 
        end
        
        function bandStop_seriesRLC(app, cutoff1, cutoff2)
            if app.HzButton.Value == true
                wc1= 2*pi*cutoff1;
                wc2= 2*pi*cutoff2;
            else
                wc1 = cutoff1;
                wc2 = cutoff2;
            end
            
            show_alert_if_same_cutoff(app, wc1, wc2);

            
            B= abs(wc2-wc1);
            w0=sqrt(wc1*wc2);
            Q = round(w0/B, 3);
            
            R = 1e3;
            L = R/B;
            C = 1/(L*w0^2);

            w =0 : w0/400 : 133*max([wc1, wc2]);
            s = j*w;

            H = (s.^2 + w0^2)./(s.^2 + s*B + w0^2);
            
            
            error_function = @(s) abs((s.^2 + w0^2)./(s.^2 + s*B + w0^2));
            error = abs(integral(error_function, j*wc1, j*wc2))/B *100;    
            
            if app.HzButton.Value == true
                semilogx(app.UIAxes2, w/(2*pi), 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w/(2*pi), angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (Hz)';
                app.UIAxes2_2.XLabel.String = 'Frequency (Hz)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L,"%.3e"), ' H'],['Capacitor Value, C = ', num2str(C,"%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B/(2*pi)), ' Hz'], ['Error = ', num2str(error), ' %']};
                
            else
                semilogx(app.UIAxes2, w, 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w, angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (rad/s)';
                app.UIAxes2_2.XLabel.String = 'Frequency (rad/s)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L,"%.3e"), ' H'],['Capacitor Value, C = ', num2str(C,"%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B), ' rad/s'],['Error = ', num2str(error), ' %']};

            end 
        end
        
        function bandPass_parallelRLC(app, cutoff1, cutoff2)
            if app.HzButton.Value == true
                wc1= 2*pi*cutoff1;
                wc2= 2*pi*cutoff2;
            else
                wc1 = cutoff1;
                wc2 = cutoff2;
            end
            
            show_alert_if_same_cutoff(app, wc1, wc2);

            
            B= abs(wc2-wc1);
            w0=sqrt(wc1*wc2); 
            Q = round(w0/B, 3);
            
            w =0 : w0/400 : 133*max([wc1, wc2]);
            s = j*w;
            H = (s*B)./(s.^2 + s*B + w0^2);
            
            R = 1000;
            L = B*R/w0^2;
            C = 1/(L*w0^2);
            
            error_function = @(s) 1 - abs((s*B)./(s.^2 + s*B + w0^2));
            error = abs(integral(error_function, j*wc1, j*wc2))/B *100;
            
            if app.HzButton.Value == true
                semilogx(app.UIAxes2, w/(2*pi), 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w/(2*pi), angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (Hz)';
                app.UIAxes2_2.XLabel.String = 'Frequency (Hz)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L,"%.3e"), ' H'],['Capacitor Value, C = ', num2str(C,"%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B/(2*pi)), ' Hz'], ['Error = ', num2str(error), ' %']};
                
            else
                semilogx(app.UIAxes2, w, 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w, angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (rad/s)';
                app.UIAxes2_2.XLabel.String = 'Frequency (rad/s)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L,"%.3e"), ' H'],['Capacitor Value, C = ', num2str(C,"%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B), ' rad/s'],['Error = ', num2str(error), ' %']};
                
            end 
        end
        
        
        function bandStop_parallelRLC(app, cutoff1, cutoff2)
            if app.HzButton.Value == true
                wc1= 2*pi*cutoff1;
                wc2= 2*pi*cutoff2;
            else
                wc1 = cutoff1;
                wc2 = cutoff2;
            end
            
            show_alert_if_same_cutoff(app, wc1, wc2);

            
            B= abs(wc2-wc1);
            w0=sqrt(wc1*wc2);
            Q = round(w0/B, 3);
            
            R = 1000;
            L = B*R/w0^2;
            C = 1/(L*w0^2);

            w =0 : w0/400 : 133*max([wc1, wc2]);
            s = j*w;
            H = (s.^2 + w0^2)./(s.^2 + s*B + w0^2);
            
            error_function = @(s) abs((s.^2 + w0^2)./(s.^2 + s*B + w0^2));
            error = abs(integral(error_function, j*wc1, j*wc2))/B *100;             
            
            if app.HzButton.Value == true
                semilogx(app.UIAxes2, w/(2*pi), 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w/(2*pi), angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (Hz)';
                app.UIAxes2_2.XLabel.String = 'Frequency (Hz)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L,"%.3e"), ' H'],['Capacitor Value, C = ', num2str(C,"%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B/(2*pi)), ' Hz'], ['Error = ', num2str(error), ' %']};
                
            else
                semilogx(app.UIAxes2, w, 20*log10((abs(H))));
                semilogx(app.UIAxes2_2, w, angle(H)*180/pi);
                app.UIAxes2.XLabel.String = 'Frequency (rad/s)';
                app.UIAxes2_2.XLabel.String = 'Frequency (rad/s)';
                app.CharacteristicsTextArea.Value = {['Resistor Value, R = ', num2str(R), ' ohm'],['Inductor Value, L = ', num2str(L,"%.3e"), ' H'],['Capacitor Value, C = ', num2str(C,"%.3e"), ' F'],['Quality Factor = ', num2str(Q)], ['Bandwidth = ', num2str(B), ' rad/s'],['Error = ', num2str(error), ' %']};
                
            end 
        end
        
        function [fullpath_with_filename, filename] = getFileInfoAndPath(app) 
           filename = strcat(app.SelectFilterDropDown.Value,'_', app.SelecttypeDropDown.Value,'.png');
            
           fileDirectory = mfilename('fullpath');
           mainDirectory = erase(fileDirectory,mfilename);
           cd(mainDirectory);
           folders = dir('image_folder_new');
           pathh = folders.folder;
           fullpath_with_filename = strcat(pathh, '\',filename);
        end
        
        function showSchematics_new(app)            
           [fullpath_with_filename, ~] = getFileInfoAndPath(app);
           ImageFile = imread(fullpath_with_filename);

            app.Image.ImageSource=ImageFile;
        end
        
        function show_defaultImage(app)
           
           filename = 'default_image.png';
            
           fileDirectory = mfilename('fullpath');
           mainDirectory = erase(fileDirectory,mfilename);
           cd(mainDirectory);
           folders = dir('image_folder_new');
           pathh = folders.folder;
           fullpath_with_filename = strcat(pathh, '\',filename);
           
           ImageFile = imread(fullpath_with_filename);

           app.Image.ImageSource=ImageFile;
        end
        
        function show_alert_if_same_cutoff(app, wc1, wc2)
            if wc1 == wc2
                uialert(app.UIFigure, 'Both Cutoff Frequencies can not be the same', 'Warning');
                uiwait(app.UIFigure);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format shortE;
            app.SelecttypeDropDown.Items = {'Series RC', 'Series RL'};
            app.SelecttypeDropDown.ItemsData = {'seriesRC', 'seriesRL'};
            app.CutoffFrequency2EditField.Visible = 'off';
            app.CutoffFrequency2EditFieldLabel.Visible = 'off';            
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            show_defaultImage(app);
            app.CharacteristicsTextArea.Tooltip = 'Value of R, L, C, Quality Factor, Bandwidth and Error(%) will appear here.';
            app.Image.Tooltip = 'Schematic Diagram of the desired filter will appear here.';
            app.ButtonGroup.Tooltip = 'Select the frequency unit.';
        end

        % Callback function
        function ShowSchematicsButtonPushed(app, event)

           [fullpath_with_filename, ~] = getFileInfoAndPath(app);
           ImageFile = imread(fullpath_with_filename);

           imagesc(app.UIAxes, ImageFile);

        end

        % Callback function
        function DropDownOpening(app, event)
            app.SelectFilterDropDownLabel 
        end

        % Value changed function: SelectFilterDropDown
        function SelectFilterDropDownValueChanged(app, event)

            filterType = app.SelectFilterDropDown.Value;

            if strcmp(filterType,'highPass')
                app.SelecttypeDropDown.Items = {'Series RC', 'Series RL'};
                app.SelecttypeDropDown.ItemsData = {'seriesRC', 'seriesRL'};
                app.CutoffFrequency2EditField.Visible = 'off';
                app.CutoffFrequency2EditFieldLabel.Visible = 'off'; 
                app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            elseif strcmp(filterType,'lowPass')
                app.SelecttypeDropDown.Items = {'Series RC', 'Series RL'};
                app.SelecttypeDropDown.ItemsData = {'seriesRC', 'seriesRL'};
                app.CutoffFrequency2EditField.Visible = 'off';
                app.CutoffFrequency2EditFieldLabel.Visible = 'off';   
                app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            elseif strcmp(filterType,'bandPass')
                app.SelecttypeDropDown.Items = {'Series RLC', 'Parallel RLC'};
                app.SelecttypeDropDown.ItemsData = {'seriesRLC', 'parallelRLC'};
                app.CutoffFrequency2EditField.Visible = 'on';
                app.CutoffFrequency2EditFieldLabel.Visible = 'on';
                app.CutoffFrequencyLabel.Text = 'Cutoff Frequency 1';
            elseif strcmp(filterType,'bandStop')
                app.SelecttypeDropDown.Items = {'Series RLC', 'Parallel RLC'};
                app.SelecttypeDropDown.ItemsData = {'seriesRLC', 'parallelRLC'};
                app.CutoffFrequency2EditField.Visible = 'on';
                app.CutoffFrequency2EditFieldLabel.Visible = 'on';
                app.CutoffFrequencyLabel.Text = 'Cutoff Frequency 1';
            end

        end

        % Button pushed function: 
        % ShowBodePlotandCircuitDiagramButton
        function ShowBodePlotandCircuitDiagramButtonPushed(app, event)

           [~, filename] = getFileInfoAndPath(app);

            filename = erase(filename,'.png');
            switch filename
                case 'lowPass_seriesRL'
                    lowPass_seriesRL(app, app.CutoffFrequencyEditField.Value);
                case 'highPass_seriesRL'
                    highPass_seriesRL(app, app.CutoffFrequencyEditField.Value);
                case 'lowPass_seriesRC'
                    lowPass_seriesRC(app, app.CutoffFrequencyEditField.Value);
                case 'highPass_seriesRC'
                    highPass_seriesRC(app, app.CutoffFrequencyEditField.Value);
                case 'bandPass_seriesRLC'
                    bandPass_seriesRLC(app, app.CutoffFrequencyEditField.Value,app.CutoffFrequency2EditField.Value);
                case 'bandStop_seriesRLC'
                    bandStop_seriesRLC(app, app.CutoffFrequencyEditField.Value,app.CutoffFrequency2EditField.Value);
                case 'bandPass_parallelRLC'
                    bandPass_parallelRLC(app, app.CutoffFrequencyEditField.Value,app.CutoffFrequency2EditField.Value);
                case 'bandStop_parallelRLC'
                    bandStop_parallelRLC(app, app.CutoffFrequencyEditField.Value,app.CutoffFrequency2EditField.Value);
            end
            showSchematics_new(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.HzButton = uiradiobutton(app.ButtonGroup);
            app.HzButton.Text = 'Hz';
            app.HzButton.Value = true;
            app.radsButton = uiradiobutton(app.ButtonGroup);
            app.radsButton.Text = 'rad/s';
            app.SelectFilterDropDownLabel = uilabel(app.UIFigure);
            app.SelectFilterDropDownLabel.Text = 'Select Filter';
            app.SelectFilterDropDown = uidropdown(app.UIFigure);
            app.SelectFilterDropDown.Items = {'High Pass', 'Low Pass', 'Band Pass', 'Band Stop'};
            app.SelectFilterDropDown.ItemsData = {'highPass', 'lowPass', 'bandPass', 'bandStop'};
            app.SelectFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFilterDropDownValueChanged, true);
            app.SelectFilterDropDown.Value = 'highPass';
            app.SelecttypeDropDownLabel = uilabel(app.UIFigure);
            app.SelecttypeDropDownLabel.Text = 'Select type';
            app.SelecttypeDropDown = uidropdown(app.UIFigure);
            app.SelecttypeDropDown.Items = {'Series RC', 'Series RL'};
            app.SelecttypeDropDown.Value = 'Series RC';
            app.PassiveFilterDesignandAnalysisLabel = uilabel(app.UIFigure);
            app.PassiveFilterDesignandAnalysisLabel.Text = 'Passive Filter Design and Analysis';
            app.ShowBodePlotandCircuitDiagramButton = uibutton(app.UIFigure, 'push');
            app.ShowBodePlotandCircuitDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowBodePlotandCircuitDiagramButtonPushed, true);
            app.ShowBodePlotandCircuitDiagramButton.Text = {'Show Bode Plot'; 'and Circuit Diagram'};
            app.CutoffFrequencyLabel = uilabel(app.UIFigure);
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            app.CutoffFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CutoffFrequencyEditField.Limits = [1 Inf];
            app.CutoffFrequencyEditField.Value = 1;
            app.CutoffFrequency2EditFieldLabel = uilabel(app.UIFigure);
            app.CutoffFrequency2EditFieldLabel.Text = 'Cutoff Frequency 2';
            app.CutoffFrequency2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CutoffFrequency2EditField.Limits = [1 Inf];
            app.CutoffFrequency2EditField.Value = 1;
            app.CharacteristicsLabel = uilabel(app.UIFigure);
            app.CharacteristicsLabel.Text = 'Characteristics';
            app.CharacteristicsTextArea = uitextarea(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Phase Plot')
            xlabel(app.UIAxes2_2, 'frequency (rad/s)')
            ylabel(app.UIAxes2_2, 'phase(H)  (degree)')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Magnitude Plot')
            xlabel(app.UIAxes2, 'frequency (rad/s)')
            ylabel(app.UIAxes2, 'magnitude(H) (dB)')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Passive_filter_design_and_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end